classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LayoutMain          matlab.ui.container.GridLayout
        LayoutProperties    matlab.ui.container.GridLayout
        SubjectPanel        matlab.ui.container.Panel
        SubjectSelect       matlab.ui.control.DropDown
        ScreenPanel         matlab.ui.container.Panel
        CalibrateButton     matlab.ui.control.Button
        ScreenNoSelect      matlab.ui.control.DropDown
        IDLabel             matlab.ui.control.Label
        ConnectedLamp       matlab.ui.control.Lamp
        ConnectButton       matlab.ui.control.Button
        DisplayYpxEdit      matlab.ui.control.NumericEditField
        DisplayXpxEdit      matlab.ui.control.NumericEditField
        XYpxLabel           matlab.ui.control.Label
        DisplayXmmEdit      matlab.ui.control.NumericEditField
        XYmmEditFieldLabel  matlab.ui.control.Label
        DisplayYmmEdit      matlab.ui.control.NumericEditField
        ScreenAddButton     matlab.ui.control.Button
        ScreenSelect        matlab.ui.control.DropDown
        UIAxes              matlab.ui.control.UIAxes
    end


    properties(SetAccess = protected)
        Config                          % Application configuration.

        Display = rfstim.display        % The stimulus display screen.
        Connected = false               % PTB is connected.
    end

    methods
        
        function initDisplay(app)
            dispname = app.Config.get('display', 'Generic');

            app.ScreenSelect.Items = rfstim.display.find();
            app.ScreenSelect.Value = dispname;

            app.selectDisplay(dispname);
        end

        function selectDisplay(app,name)
            try 
                app.Display = rfstim.display(Name=name);

                % update components
                app.DisplayXmmEdit.Value = app.Display.Dimension(1);
                app.DisplayYmmEdit.Value = app.Display.Dimension(2);
                app.DisplayXpxEdit.Value = app.Display.Resolution(1);
                app.DisplayYpxEdit.Value = app.Display.Resolution(2);
                app.ScreenSelect.Value = name;

                app.Config.set('display', name);
            catch
                % load previous
                app.selectDisplay(app.Config.get('display', 'Generic'));
            end
        end

        function initScreen(app)
            screens = Screen('Screens');
            screenitems = cell(1,numel(screens));
            for i = 1:numel(screens)
                screenitems{i} = num2str(screens(i));
            end

            app.ScreenNoSelect.Items = screenitems;
            app.ScreenNoSelect.Value = app.Config.get('screen.id', screenitems{1});
        end

        function selectScreen(app,id)
            app.ScreenNoSelect.Value = id;
            app.Config.set('screen.id', id);
        end

        function openScreen(app,id)
            app.Connected = app.Display.open(id);
            
            switch app.Connected
                case true
                    app.ConnectedLamp.Color = [0.0,1.0,0.0];
                case false
                    app.ConnectedLamp.Color = [1.0,0.0,0.0];
            end
        end

        function closeScreen(app)
            app.Display.close();
        end

    end

    properties(Access=private)
        EnableHandles
        EnableStatus
    end
  
    methods

        function enableUI(app,value)
            if value
                if isempty(app.EnableHandles)
                    return
                end

                for i = 1:length(app.EnableHandles)
                    app.EnableHandles{i}.Enable = app.EnableStatus{i};
                end
            else
                if isempty(app.EnableHandles)
                    allChildren = findobj(app.UIFigure);
                    
                    for i = 1:length(allChildren)
                        if isprop(allChildren(i), 'Enable')
                            app.EnableHandles{end+1} = allChildren(i);
                        end
                    end

                    app.EnableStatus = cell(1, length(allChildren));
                end

                for i = 1:length(app.EnableHandles)
                    app.EnableStatus{i} = app.EnableHandles{i}.Enable;
                    app.EnableHandles{i}.Enable = false;
                end
            end
        end
        
        function h = disable(app)
            app.enableUI(false);
            h = onCleanup(@() app.enableUI(true));
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Config = appconfig(Program = 'matlab-rfstim', File = 'global.mat');
            app.initDisplay();
            app.initScreen();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ScreenSelect
        function ScreenSelectValueChanged(app, event)
            app.selectDisplay(app.ScreenSelect.Value);
        end

        % Button pushed function: ScreenAddButton
        function ScreenAddButtonPushed(app, event)
            % FIXME
        end

        % Value changed function: ScreenNoSelect
        function ScreenNoSelectValueChanged(app, event)
            app.selectScreen(app.ScreenNoSelect.Value);           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.openScreen(str2double(app.ScreenNoSelect.Value));
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            onclose = app.disable(); %#ok<NASGU>

            cal = rfstim.display.calibrate(app);
            cal.wait()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LayoutMain = uigridlayout(app.UIFigure);
            app.LayoutProperties = uigridlayout(app.LayoutMain);
            app.ScreenPanel = uipanel(app.LayoutProperties);
            app.ScreenPanel.Title = 'Screen';
            app.UIAxes = uiaxes(app.ScreenPanel);
            xlabel(app.UIAxes, 'V (1)')
            ylabel(app.UIAxes, 'I (cd/m²)')
            zlabel(app.UIAxes, 'Z')
            app.ScreenSelect = uidropdown(app.ScreenPanel);
            app.ScreenSelect.Items = {};
            app.ScreenSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenSelectValueChanged, true);
            app.ScreenSelect.Value = {};
            app.ScreenAddButton = uibutton(app.ScreenPanel, 'push');
            app.ScreenAddButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenAddButtonPushed, true);
            app.ScreenAddButton.Enable = 'off';
            app.ScreenAddButton.Text = 'Add...';
            app.DisplayYmmEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayYmmEdit.Editable = 'off';
            app.XYmmEditFieldLabel = uilabel(app.ScreenPanel);
            app.XYmmEditFieldLabel.Text = 'X,Y (mm):';
            app.DisplayXmmEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayXmmEdit.Editable = 'off';
            app.XYpxLabel = uilabel(app.ScreenPanel);
            app.XYpxLabel.Text = 'X,Y (px):';
            app.DisplayXpxEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayXpxEdit.Editable = 'off';
            app.DisplayYpxEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayYpxEdit.Editable = 'off';
            app.ConnectButton = uibutton(app.ScreenPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLamp = uilamp(app.ScreenPanel);
            app.IDLabel = uilabel(app.ScreenPanel);
            app.IDLabel.Text = 'ID:';
            app.ScreenNoSelect = uidropdown(app.ScreenPanel);
            app.ScreenNoSelect.Items = {'''0''', '''1''', '''2'''};
            app.ScreenNoSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenNoSelectValueChanged, true);
            app.ScreenNoSelect.Value = '''1''';
            app.CalibrateButton = uibutton(app.ScreenPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate...';
            app.SubjectPanel = uipanel(app.LayoutProperties);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectSelect = uidropdown(app.SubjectPanel);
            app.SubjectSelect.Items = {'Generic'};
            app.SubjectSelect.Value = 'Generic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end